classdef TimeSeriesViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Display                matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        Tab2                   matlab.ui.container.Tab
        UpdateButton           matlab.ui.control.Button
        TerminalTextArea       matlab.ui.control.TextArea
        TerminalTextAreaLabel  matlab.ui.control.Label
        LoadButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
    end


    methods (Access = private)
    
        function updateplot(app)
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            % Load file
            
            
            % Plot the original data
            plot(app.UIAxes, (0:10), (10:20),'o-');
            hold(app.UIAxes, 'on'); 
            plot(app.UIAxes, [10:20], [30:40],'o-');
            grid(app.UIAxes, 'on'); 
            grid(app.UIAxes, 'minor');
            % Plot the data
            updateplot(app);
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            disp("Hi");
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.TerminalTextArea.Value = "Button update pressed";
        end

        % Value changed function: TerminalTextArea
        function TerminalTextAreaValueChanged(app, event)
            value = app.TerminalTextArea.Value;
            disp(value)
            app.TerminalTextArea.Value = "Hello";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Display = uifigure('Visible', 'off');
            app.Display.Name = 'Time Series Analysis';
            app.SaveButton = uibutton(app.Display, 'push');
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Display, 'push');
            app.LoadButton.Text = 'Load';
            app.TerminalTextAreaLabel = uilabel(app.Display);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.TerminalTextArea = uitextarea(app.Display);
            app.TerminalTextArea.ValueChangedFcn = createCallbackFcn(app, @TerminalTextAreaValueChanged, true);
            app.UpdateButton = uibutton(app.Display, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TabGroup = uitabgroup(app.Display);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Display')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Data')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.Display.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimeSeriesViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Display)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Display)
        end
    end
end