classdef main_R2017b_191222 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ControlTab                    matlab.ui.container.Tab
        UpdateButton                  matlab.ui.control.Button
        TimeSeriesPlotGeneratorLabel  matlab.ui.control.Label
        TEsEditField                  matlab.ui.control.NumericEditField
        TEsEditFieldLabel             matlab.ui.control.Label
        T0sEditField                  matlab.ui.control.NumericEditField
        T0sLabel                      matlab.ui.control.Label
        DemoButton                    matlab.ui.control.Button
        ReportTextArea                matlab.ui.control.TextArea
        ReportTextAreaLabel           matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Tab2                          matlab.ui.container.Tab
    end

    properties (Constant)
        GUIDataVar = 'GUIDataHandle'

    end
    
    
    properties (Access = private)
        DataHandle % Description
        DataAnalyzer
        
        TableConfig
    end
    
    methods (Access = private)
        
        function preprcDataTable(app, dataTable)
            % Preprocess the data %! TODO: implement somewhere an automated script for the preprocessing
            app.DataHandle = parseTimeSeries(dataTable);
            % ... Get other stuffs too
            
            % Export the GUIDataHandle to workspace
            assignin('base', app.GUIDataVar, app.DataHandle);
            
            % List the fieldnames
            fNames = fieldnames(app.DataHandle);
            n = numel(fNames);

            % Get the "TimeSeries" fields by their names
            tsFields = [];
    
            for i = 1: n
                curF = fNames(i);
                isTs = isa(app.DataHandle.(curF{1}), 'TimeSeries'); % true
                if(isTs)
                   tsFields = [tsFields fNames(i)]; 
                end
            end
            
            % Updated the table
            app.UITable.Data(1:numel(tsFields),app.TableConfig.selCol) = false;
            app.UITable.ColumnFormat = {'logical'};
            app.UITable.RowName = tsFields;
        end
    
    end
    
    methods (Access = public)
        
        function results = show(app, str)
            t = string(datetime("now"));
            set(app.ReportTextArea, 'Value', sprintf('%s AMDC Flight Analyzer >> \n%s', t, str));
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TableConfig.selCol = 1;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            %if (indices(2) == app.TableConfig.selCol)
            app.UITable.Data(indices(1),app.TableConfig.selCol) = ~ app.UITable.Data(indices(1),app.TableConfig.selCol);
            %end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % open dummy figure
            f=figure();
            %bring figure to front focus
            drawnow;
            
            dir = [pwd, ''];
            [file, path] = uigetfile(fullfile(dir,'*.**'),'Choose Flight Log \n');
            if ~file
                fprintf('No Log file selected!\n');
            else
                fprintf('Load flight log %s!\n', file);
                tmp = load(file);
                dataTable = tmp.data_table;
                app.preprcDataTable(dataTable);
                
                app.show(sprintf("Load file %s succesfully!", file));
            end
            delete(f);            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           % Check the selected list
            selectedId = app.UITable.RowName(app.UITable.Data(:, app.TableConfig.selCol) == true);
            str = "Selected topics: ";
            for i = 1: numel(selectedId)
                str = sprintf("%s\n%s", str, selectedId{i});
            end  
            
            % Get the time series
            tsArr = cell(1, numel(selectedId));
            for i = 1: numel(selectedId)
                tsArr{i} = app.DataHandle.(selectedId{i});                
            end
            
            % Assign to a TimeSeriesViewer
            if(numel(tsArr) > 0)
                
                % Read the plotting interval            
                tmp = TSViewer(tsArr);
                
                str = sprintf("%s\nTime Interval [%f, %f] s", str, app.T0sEditField.Value, app.TEsEditField.Value);
                if(app.T0sEditField.Value == 0 && app.TEsEditField.Value == 0)
                    app.T0sEditField.Value = -inf;
                    app.TEsEditField.Value = inf;
                end
                
                tmp.plot("TimeInterval", [app.T0sEditField.Value, app.TEsEditField.Value]); 
            end
            
             % Display      
            app.show(str);
        end

        % Button pushed function: DemoButton
        function DemoButtonPushed(app, event)
            fprintf("Load flight log flight_data_parsed_09-Sep-2022.mat \n");
            tmp = load("TestData/TestData1.mat");
            dataTable = tmp.data_table;
            app.preprcDataTable(dataTable);
            
            app.show(sprintf("Auto Load succes!"));
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data(:, app.TableConfig.selCol) = false;
            app.show("Cleared");
            app.T0sEditField.Value = 0;
            app.TEsEditField.Value = 0;
            close all;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Update the modified variable from the app;
            tmpDataTable = evalin('base',app.GUIDataVar);
            app.preprcDataTable(tmpDataTable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.UITable = uitable(app.ControlTab);
            app.UITable.ColumnName = {'Select'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveButton = uibutton(app.ControlTab, 'push');
            app.SaveButton.Text = 'Save';
            app.PlotButton = uibutton(app.ControlTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.ControlTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LoadButton = uibutton(app.ControlTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ReportTextAreaLabel = uilabel(app.ControlTab);
            app.ReportTextAreaLabel.Text = 'Report';
            app.ReportTextArea = uitextarea(app.ControlTab);
            app.DemoButton = uibutton(app.ControlTab, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @DemoButtonPushed, true);
            app.DemoButton.Text = 'Demo';
            app.T0sLabel = uilabel(app.ControlTab);
            app.T0sLabel.Text = 'T0 [s]';
            app.T0sEditField = uieditfield(app.ControlTab, 'numeric');
            app.TEsEditFieldLabel = uilabel(app.ControlTab);
            app.TEsEditFieldLabel.Text = 'TE [s]';
            app.TEsEditField = uieditfield(app.ControlTab, 'numeric');
            app.TimeSeriesPlotGeneratorLabel = uilabel(app.ControlTab);
            app.TimeSeriesPlotGeneratorLabel.Text = 'Time Series - Plot Generator';
            app.UpdateButton = uibutton(app.ControlTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_R2017b_191222

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end