classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TabGroup2             matlab.ui.container.TabGroup
        Tab_2                 matlab.ui.container.Tab
        LoadButton            matlab.ui.control.Button
        TestButton            matlab.ui.control.Button
        autoButton            matlab.ui.control.Button
        DataDropDown          matlab.ui.control.DropDown
        DataDropDownLabel     matlab.ui.control.Label
        Tab2_2                matlab.ui.container.Tab
        RightPanel            matlab.ui.container.Panel
        TabGroup3             matlab.ui.container.TabGroup
        Tab_3                 matlab.ui.container.Tab
        MonitorTextAreaLabel  matlab.ui.control.Label
        MonitorTextArea       matlab.ui.control.TextArea
        UIAxes                matlab.ui.control.UIAxes
        Tab2_3                matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        Menu                  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        Menu_2                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DataHandle % Description
        DataAnalyzer
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataHandle = 0;
            app.DataDropDownOpening();
            %app.TestButtonPushed();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {447, 447};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {285, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function LoadButtonValueChanged(app, event)
            value = app.LoadButton.Value;
            disp(value);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % open dummy figure
            f=figure();
            %bring figure to front focus
            drawnow;
            
            dir = [pwd, ''];
            [file, path] = uigetfile(fullfile(dir,'*.**'),'Choose Flight Log \n');
            if ~file
                fprintf('No Log file selected!\n');
            else
                fprintf('Load flight log %s!\n', file);
                tmp = load(file);
                app.DataHandle = tmp.data_table;
            end
            
            delete(f);
            app.DataDropDownOpening();
            
            % Assign the data handle to the analyzer
            %
            %
        end

        % Drop down opening function: DataDropDown
        function DataDropDownOpening(app, event)
            try
                fNames = fieldnames(app.DataHandle);
                app.DataDropDown.Items = fNames;
                app.DataDropDown.ItemsData = 1:numel(fNames);
            catch
                app.DataDropDown.Items = "Empty";
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            t = string(datetime("now"));
            set(app.MonitorTextArea, 'Value', sprintf('%s >> AMDC Flight Log Analyzer', t));
            
            x = 1:0.1:20;
            plot(app.UIAxes,x, sin(x),'-o', "DisplayName", "sin x");
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,x, cos(x),'-o', "DisplayName", "cos x");
            %legend
            FormatAxis(app.UIAxes, "Width", 12, "AspectRatio", 12/8, 'MarkerSize', 3);
        end

        % Button pushed function: autoButton
        function autoButtonPushed(app, event)
            % Get attitude data
            AttTopicInfo.Name = "VN200_Attitude";
            AttTopicInfo.Unit = "deg";
            AttTopicInfo.fs  = 800;
            attTs = Topics2Ts(app.DataHandle, "ATTITUDE", ["Phi", "Theta", "Psi"], AttTopicInfo);
            
            
            % Get rate data
            RateTopicInfo.Name = "VN200_AttitudeRate";
            RateTopicInfo.Unit = "rad/s";
            RateTopicInfo.fs  = 800;
            rateTs = Topics2Ts(app.DataHandle, "ATTITUDE_RATE", ["p", "q", "r"], RateTopicInfo);
            
            app.UIFigure
            x = 1:0.1:20;
            plot(x, sin(x),'-o', "DisplayName", "sin x");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.DataDropDownLabel = uilabel(app.Tab_2);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.Tab_2);
            app.DataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DataDropDownOpening, true);
            app.autoButton = uibutton(app.Tab_2, 'push');
            app.autoButton.ButtonPushedFcn = createCallbackFcn(app, @autoButtonPushed, true);
            app.autoButton.Text = 'auto';
            app.TestButton = uibutton(app.Tab_2, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.LoadButton = uibutton(app.Tab_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.Tab_3 = uitab(app.TabGroup3);
            app.Tab_3.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab_3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MonitorTextArea = uitextarea(app.Tab_3);
            app.MonitorTextAreaLabel = uilabel(app.Tab_3);
            app.MonitorTextAreaLabel.Text = 'Monitor';
            app.Tab2_3 = uitab(app.TabGroup3);
            app.Tab2_3.Title = 'Tab2';
            app.UITable = uitable(app.Tab2_3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Menu_2 = uimenu(app.Menu2);
            app.Menu_2.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end