classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TestButton            matlab.ui.control.Button
        DataDropDown          matlab.ui.control.DropDown
        DataDropDownLabel     matlab.ui.control.Label
        LoadButton            matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        MonitorTextAreaLabel  matlab.ui.control.Label
        MonitorTextArea       matlab.ui.control.TextArea
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DataHandle % Description
        DataAnalyzer
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataHandle = 0;
            app.DataDropDownOpening();
            %app.TestButtonPushed();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function LoadButtonValueChanged(app, event)
            value = app.LoadButton.Value;
            disp(value);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % open dummy figure
            f=figure();
            %bring figure to front focus
            drawnow;
            
            dir = [pwd, ''];
            [file, path] = uigetfile(fullfile(dir,'*.**'),'Choose Flight Log \n');
            if ~file
                fprintf('No Log file selected!\n');
            else
                fprintf('Load flight log %s!\n', file);
                tmp = load(file);
                app.DataHandle = tmp.data_table;
            end
            
            delete(f);
            app.DataDropDownOpening();
            
            % Assign the data handle to the analyzer
            %
            %
        end

        % Drop down opening function: DataDropDown
        function DataDropDownOpening(app, event)
            try
                fNames = fieldnames(app.DataHandle);
                app.DataDropDown.Items = fNames;
            catch
                app.DataDropDown.Items = "Empty";
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            t = string(datetime("now"));
            set(app.MonitorTextArea, 'Value', sprintf('%s >> AMDC Flight Log Analyzer', t));
            
            x = 1:0.1:20;
            plot(app.UIAxes,x, sin(x),'-o', "DisplayName", "sin x");
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,x, cos(x),'-o', "DisplayName", "cos x");
            %legend
            FormatAxis(app.UIAxes, "Width", 12, "AspectRatio", 12/8, 'MarkerSize', 3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DataDropDownLabel = uilabel(app.LeftPanel);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.LeftPanel);
            app.DataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DataDropDownOpening, true);
            app.TestButton = uibutton(app.LeftPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MonitorTextArea = uitextarea(app.RightPanel);
            app.MonitorTextAreaLabel = uilabel(app.RightPanel);
            app.MonitorTextAreaLabel.Text = 'Monitor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end