classdef main_R2018b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        UITable            matlab.ui.control.Table
        SaveButton         matlab.ui.control.Button
        PlotButton         matlab.ui.control.Button
        ClearButton        matlab.ui.control.Button
        LoadButton         matlab.ui.control.Button
        TextAreaLabel      matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        AutoButton         matlab.ui.control.Button
        T0sLabel           matlab.ui.control.Label
        T0sEditField       matlab.ui.control.NumericEditField
        TEsEditFieldLabel  matlab.ui.control.Label
        TEsEditField       matlab.ui.control.NumericEditField
        Tab2               matlab.ui.container.Tab
    end

    
    properties (Access = private)
        DataHandle % Description
        DataAnalyzer
        
        TableConfig
    end
    
    methods (Access = private)
        
        
        function app = LoadFile(app, file)
            % Get the file data handle
            fprintf('Load flight log %s!\n', file);
            tmp = load(file);
            app.DataHandle = getVN200Data(tmp.data_table);
            
            % List the fieldnames
            fNames = fieldnames(app.DataHandle);
            n = numel(fNames);

            % Get the "TimeSeries" fields by their names
            tsFields = [];
    
            for i = 1: n
                curF = fNames(i);
                isTs = isa(app.DataHandle.(curF{1}), 'TimeSeries'); % true
                if(isTs)
                   tsFields = [tsFields fNames(i)]; 
                end
            end
            
            % Updated the table
            app.UITable.Data(1:numel(tsFields),app.TableConfig.selCol) = false;
            app.UITable.ColumnFormat = {'logical'};
            app.UITable.RowName = tsFields;
            
            app.show(sprintf("Load file %s succesfully!", file));
        end
    end
    
    methods (Access = public)
        
        function results = show(app, str)
            t = string(datetime("now"));
            set(app.TextArea, 'Value', sprintf('%s AMDC Flight Analyzer >> \n%s', t, str));
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TableConfig.selCol = 1;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if (indices(2) == app.TableConfig.selCol)
                app.UITable.Data(indices(1),app.TableConfig.selCol) = ~ app.UITable.Data(indices(1),app.TableConfig.selCol);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % open dummy figure
            f=figure();
            %bring figure to front focus
            drawnow;
            
            dir = [pwd, ''];
            [file, path] = uigetfile(fullfile(dir,'*.**'),'Choose Flight Log \n');
            if ~file
                fprintf('No Log file selected!\n');
            else
                app.LoadFile(file);
            end
            delete(f);            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           % Check the selected list
            selectedId = app.UITable.RowName(app.UITable.Data(:, app.TableConfig.selCol) == true);
            str = "The selected topics: ";
            for i = 1: numel(selectedId)
                str = sprintf("%s\n%s", str, selectedId{i});
            end  
            
            % Get the time series
            tsArr = cell(1, numel(selectedId));
            for i = 1: numel(selectedId)
                tsArr{i} = app.DataHandle.(selectedId{i});                
            end
            
            % Assign to a TimeSeriesViewer
            if(numel(tsArr) > 0)
                
                % Read the plotting interval            
                tmp = TSViewer(tsArr);
                
                str = sprintf("%s\nTime Interval [%f, %f] s", str, app.T0sEditField.Value, app.TEsEditField.Value);
                tmp.plot("TimeInterval", [app.T0sEditField.Value, app.TEsEditField.Value]); 
            end
            
             % Display      
            app.show(str);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.LoadFile("flight_data_parsed_09-Sep-2022.mat");            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data(:, app.TableConfig.selCol) = false;
            app.show("Cleared");
            close all;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Select'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveButton = uibutton(app.Tab, 'push');
            app.SaveButton.Text = 'Save';
            app.PlotButton = uibutton(app.Tab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.Tab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LoadButton = uibutton(app.Tab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TextAreaLabel = uilabel(app.Tab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Tab);
            app.AutoButton = uibutton(app.Tab, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.T0sLabel = uilabel(app.Tab);
            app.T0sLabel.Text = 'T0 [s]';
            app.T0sEditField = uieditfield(app.Tab, 'numeric');
            app.TEsEditFieldLabel = uilabel(app.Tab);
            app.TEsEditFieldLabel.Text = 'TE [s]';
            app.TEsEditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_R2018b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end